classdef reimu_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        EditField      matlab.ui.control.NumericEditField
        Label_2        matlab.ui.control.Label
        Button         matlab.ui.control.Button
        TextArea       matlab.ui.control.TextArea
        Label          matlab.ui.control.Label
        ReimuGraph     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        list % ドロップダウンのリスト
    end
    
    methods (Access = private)
        
        function read_outside_text(app)
            app.list = readcell("list.txt");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "霊夢アプリ";

            read_outside_text(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % disp("ボタンが押されました");
            app.TextArea.Value = ...
                [app.TextArea.Value; "ボタンが押されました"];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            time_vec = 0:0.01:10;
            y_vec = time_vec .^ value;

            plot(app.ReimuGraph, time_vec, y_vec);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.TextArea.Value = ...
                [app.TextArea.Value; string(value)];
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            read_outside_text(app);
            for k = 1:length(app.list)
                app.DropDown.Items(k) = app.list(k);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReimuGraph = uiaxes(app.UIFigure);
            title(app.ReimuGraph, 'Title')
            xlabel(app.ReimuGraph, 'X')
            ylabel(app.ReimuGraph, 'Y')
            zlabel(app.ReimuGraph, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'メッセージ';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'霊夢です'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'ボタン';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '次数';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reimu_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end